classdef GUI_Quantizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PCMQuantizerLabel              matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        NofileLabel                    matlab.ui.control.Label
        MessageFileNameegMessagetxtLabel  matlab.ui.control.Label
        MessageFileEditField           matlab.ui.control.EditField
        TimeFileNameegTimetxtLabel     matlab.ui.control.Label
        TimeFileEditField              matlab.ui.control.EditField
        formButtonGroup                matlab.ui.container.ButtonGroup
        UniformButton                  matlab.ui.control.RadioButton
        NonUniformButton               matlab.ui.control.RadioButton
        OptionsSectionLabel            matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        PeakQuantizationLevelMpEditFieldLabel  matlab.ui.control.Label
        PeakQuantizationLevelMpEditField  matlab.ui.control.NumericEditField
        NumberofLevelsLEditFieldLabel  matlab.ui.control.Label
        NumberofLevelsLEditField       matlab.ui.control.NumericEditField
        LawQuantizerEditFieldLabel     matlab.ui.control.Label
        LawQuantizerEditField          matlab.ui.control.NumericEditField
        DisplayFigureButton            matlab.ui.control.Button
        MSEButton                      matlab.ui.control.Button
        MSEEditField                   matlab.ui.control.NumericEditField
        midButtonGroup                 matlab.ui.container.ButtonGroup
        MidRiseButton                  matlab.ui.control.RadioButton
        MidTreadButton                 matlab.ui.control.RadioButton
        SamplingFrequencyfsEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyfsEditFieldLabel  matlab.ui.control.Label
        ENTERNEWDATAButton             matlab.ui.control.Button
        SpaceLabel                     matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        t % Description
        m % Description
        L % Description
        mu % Description
        mp % Description
        fs % Description
        ts % Description
        n_samples % Description
        ts_vec % Description
        delta % Description
        QuantizedMessage
    end
    
    properties (Access = private)
        Property12 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            MessageFile = app.MessageFileEditField.Value;
            TimeFile = app.TimeFileEditField.Value;

            if (exist(MessageFile, 'file') == 0) || (exist(TimeFile, 'file') == 0)
                app.NofileLabel.Visible = 'on';
                app.NofileLabel.Text = 'Files Not Found!';
            else
                app.NofileLabel.Text = 'Files Found';
                app.NofileLabel.Visible = 'off';
                app.m= readmatrix(MessageFile);
                app.t = readmatrix(TimeFile);
                
                app.OptionsSectionLabel.Visible = 'on';
                app.formButtonGroup.Visible = 'on';
                app.UniformButton.Visible = 'on';
                app.NonUniformButton.Visible = 'on';
                app.midButtonGroup.Visible = 'on';
                app.MidRiseButton.Visible = 'on';
                app.MidTreadButton.Visible = 'on';
                
                app.ParametersLabel.Visible = 'on';
                app.SamplingFrequencyfsEditFieldLabel.Visible = 'on';
                app.SamplingFrequencyfsEditField.Visible = 'on';
                app.NumberofLevelsLEditField.Visible = 'on';
                app.NumberofLevelsLEditFieldLabel.Visible = 'on';
                app.PeakQuantizationLevelMpEditField.Visible = 'on';
                app.PeakQuantizationLevelMpEditFieldLabel.Visible = 'on';
                %app.LawQuantizerEditField.Visible = 'on';
                %app.LawQuantizerEditFieldLabel.Visible = 'on';
                
                app.DisplayFigureButton.Visible = 'on';
                app.MSEButton.Visible = 'on';
                
                
                app.SpaceLabel.Visible = 'on';
                app.ENTERNEWDATAButton.Visible = 'on';
                
       
                app.MessageFileEditField.Visible = 'off';
                app.MessageFileNameegMessagetxtLabel.Visible = 'off';
                app.TimeFileEditField.Visible = 'off';
                app.TimeFileNameegTimetxtLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                
               
                app.UniformButton.Value = 1;
                app.NonUniformButton.Value = 0;
                app.MidRiseButton.Value = 1;
                app.MidTreadButton.Value = 0;
            end
        end

        % Selection changed function: formButtonGroup
        function formButtonGroupSelectionChanged(app, event)
    
            if(app.UniformButton.Value == 1)
                app.LawQuantizerEditField.Visible = 'off';
                app.LawQuantizerEditFieldLabel.Visible = 'off';
                app.midButtonGroup.Visible = 'on';

            end
            if(app.NonUniformButton.Value == 1)
                app.LawQuantizerEditField.Visible = 'on';
                app.LawQuantizerEditFieldLabel.Visible = 'on';
                app.midButtonGroup.Visible = 'off';
             
            
            end
            
        end

        % Size changed function: formButtonGroup
        function formButtonGroupSizeChanged(app, event)
            
        end

        % Button pushed function: ENTERNEWDATAButton
        function ENTERNEWDATAButtonPushed(app, event)
            
                
                app.OptionsSectionLabel.Visible = 'off';
                app.formButtonGroup.Visible = 'off';
                app.UniformButton.Visible = 'off';
                app.NonUniformButton.Visible = 'off';
                app.midButtonGroup.Visible = 'off';
                app.MidRiseButton.Visible = 'off';
                app.MidTreadButton.Visible = 'off';
                
                app.ParametersLabel.Visible = 'off';
                app.SamplingFrequencyfsEditFieldLabel.Visible = 'off';
                app.SamplingFrequencyfsEditField.Visible = 'off';
                app.NumberofLevelsLEditField.Visible = 'off';
                app.NumberofLevelsLEditFieldLabel.Visible = 'off';
                app.PeakQuantizationLevelMpEditField.Visible = 'off';
                app.PeakQuantizationLevelMpEditFieldLabel.Visible = 'off';
                app.LawQuantizerEditField.Visible = 'off';
                app.LawQuantizerEditFieldLabel.Visible = 'off';
                
                app.DisplayFigureButton.Visible = 'off';
                app.MSEButton.Visible = 'off';
                app.MSEEditField.Visible = 'off';
                
                
                app.SpaceLabel.Visible = 'off';
                app.ENTERNEWDATAButton.Visible = 'off';
                
       
                app.MessageFileEditField.Visible = 'on';
                app.MessageFileNameegMessagetxtLabel.Visible = 'on';
                app.TimeFileEditField.Visible = 'on';
                app.TimeFileNameegTimetxtLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                
               
                app.UniformButton.Value = 1;
                app.NonUniformButton.Value = 0;
                app.MidRiseButton.Value = 1;
                app.MidTreadButton.Value = 0;
                
        end

        % Button pushed function: DisplayFigureButton
        function DisplayFigureButtonPushed(app, event)
            app.QuantizedMessage = 0;
            app.fs = app.SamplingFrequencyfsEditField.Value;
            app.L = app.NumberofLevelsLEditField.Value;
            app.mp = app.PeakQuantizationLevelMpEditField.Value;
            app.mu = app.LawQuantizerEditField.Value;
            
            app.delta = (2*app.mp)/app.L;
            app.ts = 1/app.fs;
            app.n_samples = app.t(end)/ app.ts;
            app.ts_vec = app.ts .* (0:1:app.n_samples);

            
            if(app.UniformButton.Value == 1)
                if(app.MidRiseButton.Value == 1)
                    l_values_mr= -app.mp + app.delta/2 : app.delta : app.mp-app.delta / 2 ; 
                    shif_m_mr = (app.m- (-app.mp) ) / app.delta + 1/2 ;   
                    m_l_index_mr = round(shif_m_mr);
                    m_l_index_mr = min ( m_l_index_mr , app.L); 
                    
                    
                    c=1;
                    for i = 1:length(app.ts_vec)
                        for j=1:length(app.t)
                            if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                index_mr(c) = j;
                                c = c+1;
                                break;
                            end
                        end
                    end
                    
                    for i = 1:length(index_mr)
                        l_index_mr(i) = m_l_index_mr(index_mr(i));
                    end
                    
                    for i = 1:length(l_index_mr)
                        quantized_mr(i) = l_values_mr(l_index_mr(i));
                    end
                    
                    
                    quantized_mr1 =  quantized_mr;
                    ts_vec_mr1 = app.ts_vec;
                    if(app.ts_vec(end) ~= app.t(end))
                        ts_vec_mr1(end+1) = app.t(end); 
                        quantized_mr1(end+1) = quantized_mr1(end);
                    end
                    
                    app.QuantizedMessage = quantized_mr;
                    
                    figure('Name','Mid-Rise Uniform PCM Quantization');
                    plot(app.t, app.m,'b'); xlabel('Time(sec)'); ylabel('Amplitude(V)'); title('PCM Quantization - Midrise Uniform'); hold on;
                    stem(app.t(index_mr),app.m(index_mr),'g','LineWidth',1.2);
                    stairs(ts_vec_mr1, quantized_mr1,'r','LineWidth',1.2); grid on;
                    legend('Input Signal','Sampled Input Signal','Quantized Signal');
                    
                end
                
               if(app.MidTreadButton.Value == 1)
                   
                    app.delta = (2*app.mp)/app.L;
                    l_values_mt= -app.mp + app.delta : app.delta : app.mp;  
                    shif_m_mt = (app.m- (-app.mp) ) / app.delta;   
                    m_l_index_mt=round(shif_m_mt); 
                    
                    
                    for i= 1:length(m_l_index_mt)
                        if(m_l_index_mt(i)==0)
                            m_l_index_mt(i) =1;
                        end
                    end
                    
                    c=1;
                    for i = 1:length(app.ts_vec)
                        for j=1:length(app.t)
                            if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                index_mt(c) = j;
                                c = c+1;
                                break;
                            end
                        end
                    end

                    for i = 1:length(index_mt)
                        l_index_mt(i) = m_l_index_mt(index_mt(i));
                    end
                    

                    for i = 1:length(l_index_mt)
                        quantized_mt(i) = l_values_mt(l_index_mt(i));
                    end
                    
                    app.QuantizedMessage = quantized_mt;
                   
                    
                    quantized_mt1 =  quantized_mt;
                    ts_vec_mt1 = app.ts_vec;
                    if(app.ts_vec(end) ~= app.t(end))
                        ts_vec_mt1(end+1) = app.t(end); 
                        quantized_mt1(end+1) = quantized_mt1(end);
                    end
                    
                    app.QuantizedMessage = quantized_mt;
                    
                    figure('Name','Mid-Rise Uniform PCM Quantization');
                    plot(app.t, app.m,'b'); xlabel('Time(sec)'); ylabel('Amplitude(V)'); title('PCM Quantization - Midrise Uniform'); hold on;
                    stem(app.t(index_mt),app.m(index_mt),'g','LineWidth',1.2);
                    stairs(ts_vec_mt1, quantized_mt1,'r','LineWidth',1.2); grid on;
                    legend('Input Signal','Sampled Input Signal','Quantized Signal');
               end
            end
            if(app.NonUniformButton.Value == 1)
                  
                        m1=app.m+app.mp; 
                        m_h=m1/(2*app.mp);
                        y= log(1+app.mu*m_h)/log(1+app.mu); %for non_uniform quantization
                        app.mp = 1;
                        app.delta = (app.mp)/app.L;
                        l_values= app.delta : app.delta : app.mp;  
                        shif_m = (y)  / app.delta;   
                        m_l_index=round(shif_m);
    
                        
                        for i= 1:length(m_l_index)
                            if(m_l_index(i)==0)
                                m_l_index(i) =1;
                            end
                        end
                        
                        c=1;
                        for i = 1:length(app.ts_vec)
                           for j=1:length(app.t)
                              if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                 index(c) = j;
                                 c = c+1;
                                 break;
                              end
                           end
                        end
                          
                        for i = 1:length(index)
                            l_index(i) = m_l_index(index(i));
                        end
                        
             
                        for i = 1:length(l_index)
                            app.QuantizedMessage(i)= l_values(l_index(i));
                        end
                        
                        figure('Name','Non-Uniform PCM Quantization');
                        plot(app.t, y,'b'); xlabel('Time(sec)'); ylabel('Amplitude(V)'); title('PCM Quantization - Non-Uniform'); hold on;
                        stem(app.t(index),y(index),'g','LineWidth',1.2);
                        stairs(app.ts_vec, app.QuantizedMessage,'r','LineWidth',1.2); grid on;

                        legend('Input Signal (Normalized and Compressed)','Sampled Input Signal','Quantized Signal');
                        
            end
        
        end

        % Value changed function: SamplingFrequencyfsEditField
        function SamplingFrequencyfsEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: MSEButton
        function MSEButtonPushed(app, event)
            app.MSEEditField.Visible = 'on';
            app.QuantizedMessage = 0;
            app.fs = app.SamplingFrequencyfsEditField.Value;
            app.L = app.NumberofLevelsLEditField.Value;
            app.mp = app.PeakQuantizationLevelMpEditField.Value;
            app.mu = app.LawQuantizerEditField.Value;
            
            app.delta = (2*app.mp)/app.L;
            app.ts = 1/app.fs;
            app.n_samples = app.t(end)/ app.ts;
            app.ts_vec = app.ts .* (0:1:app.n_samples);

            
            if(app.UniformButton.Value == 1)
                if(app.MidRiseButton.Value == 1)
                    l_values_mr= -app.mp + app.delta/2 : app.delta : app.mp-app.delta / 2 ; 
                    shif_m_mr = (app.m- (-app.mp) ) / app.delta + 1/2 ;   
                    m_l_index_mr = round(shif_m_mr);
                    m_l_index_mr = min ( m_l_index_mr , app.L); 
                    
                    
                    c=1;
                    for i = 1:length(app.ts_vec)
                        for j=1:length(app.t)
                            if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                index_mr(c) = j;
                                c = c+1;
                                break;
                            end
                        end
                    end
                    
                    for i = 1:length(index_mr)
                        l_index_mr(i) = m_l_index_mr(index_mr(i));
                    end
                    
                    for i = 1:length(l_index_mr)
                        quantized_mr(i) = l_values_mr(l_index_mr(i));
                    end
                    
                    
                    quantized_mr1 =  quantized_mr;
                    ts_vec_mr1 = app.ts_vec;
                    if(app.ts_vec(end) ~= app.t(end))
                        ts_vec_mr1(end+1) = app.t(end); 
                        quantized_mr1(end+1) = quantized_mr1(end);
                    end
                    
                    app.QuantizedMessage = quantized_mr;
                    
                    %MSE Calculations
                     for(i = 1:length(index_mr))

                         d(i) = app.m(index_mr(i)); 
                     end

                     er = d - quantized_mr;
                     er = er.^2;
                     app.MSEEditField.Value = mean(er);


                end
                
               if(app.MidTreadButton.Value == 1)
                   
                    app.delta = (2*app.mp)/app.L;
                    l_values_mt= -app.mp + app.delta : app.delta : app.mp;  
                    shif_m_mt = (app.m- (-app.mp) ) / app.delta;   
                    m_l_index_mt=round(shif_m_mt); 
                    
                    
                    for i= 1:length(m_l_index_mt)
                        if(m_l_index_mt(i)==0)
                            m_l_index_mt(i) =1;
                        end
                    end
                    
                    c=1;
                    for i = 1:length(app.ts_vec)
                        for j=1:length(app.t)
                            if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                index_mt(c) = j;
                                c = c+1;
                                break;
                            end
                        end
                    end

                    for i = 1:length(index_mt)
                        l_index_mt(i) = m_l_index_mt(index_mt(i));
                    end
                    

                    for i = 1:length(l_index_mt)
                        quantized_mt(i) = l_values_mt(l_index_mt(i));
                    end
                    
                    app.QuantizedMessage = quantized_mt;
                   
                    
                    quantized_mt1 =  quantized_mt;
                    ts_vec_mt1 = app.ts_vec;
                    if(app.ts_vec(end) ~= app.t(end))
                        ts_vec_mt1(end+1) = app.t(end); 
                        quantized_mt1(end+1) = quantized_mt1(end);
                    end
                    
                    app.QuantizedMessage = quantized_mt;
                    
                    %MSE Calculations
                     for(i = 1:length(index_mt))

                         d(i) = app.m(index_mt(i)); 
                     end

                     er = d - quantized_mt;
                     er = er.^2;
                     app.MSEEditField.Value = mean(er);
               end
            end
            if(app.NonUniformButton.Value == 1)
                  
                        m1=app.m+app.mp; 
                        m_h=m1/(2*app.mp);
                        y= log(1+app.mu*m_h)/log(1+app.mu); %for non_uniform quantization
                        app.mp = 1;
                        app.delta = (app.mp)/app.L;
                        l_values= app.delta : app.delta : app.mp;  
                        shif_m = (y)  / app.delta;   
                        m_l_index=round(shif_m);
    
                        
                        for i= 1:length(m_l_index)
                            if(m_l_index(i)==0)
                                m_l_index(i) =1;
                            end
                        end
                        
                        c=1;
                        for i = 1:length(app.ts_vec)
                           for j=1:length(app.t)
                              if(round(app.t(j),3) == round(app.ts_vec(i),3))
                                 index(c) = j;
                                 c = c+1;
                                 break;
                              end
                           end
                        end
                          
                        for i = 1:length(index)
                            l_index(i) = m_l_index(index(i));
                        end
                        
             
                        for i = 1:length(l_index)
                            app.QuantizedMessage(i)= l_values(l_index(i));
                        end
                        
                        %MSE Calculations
     
                     for i = 1:length(app.QuantizedMessage)
                         er(i) = y(index(i))-app.QuantizedMessage(i);
                         er(i) = er(i)^2;
                     end
                     MSE = mean(er);
                     app.MSEEditField.Value = mean(er);

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PCMQuantizerLabel = uilabel(app.UIFigure);
            app.PCMQuantizerLabel.Text = 'PCM Quantizer';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NofileLabel = uilabel(app.UIFigure);
            app.NofileLabel.Visible = 'off';
            app.NofileLabel.Text = '';
            app.MessageFileNameegMessagetxtLabel = uilabel(app.UIFigure);
            app.MessageFileNameegMessagetxtLabel.Text = 'Message File Name (eg. Message.txt)';
            app.MessageFileEditField = uieditfield(app.UIFigure, 'text');
            app.TimeFileNameegTimetxtLabel = uilabel(app.UIFigure);
            app.TimeFileNameegTimetxtLabel.Text = 'Time File Name (eg. Time.txt)';
            app.TimeFileEditField = uieditfield(app.UIFigure, 'text');
            app.formButtonGroup = uibuttongroup(app.UIFigure);
            app.formButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @formButtonGroupSelectionChanged, true);
            app.formButtonGroup.Visible = 'off';
            app.formButtonGroup.SizeChangedFcn = createCallbackFcn(app, @formButtonGroupSizeChanged, true);
            app.UniformButton = uiradiobutton(app.formButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.NonUniformButton = uiradiobutton(app.formButtonGroup);
            app.NonUniformButton.Text = 'Non-Uniform';
            app.OptionsSectionLabel = uilabel(app.UIFigure);
            app.OptionsSectionLabel.Visible = 'off';
            app.OptionsSectionLabel.Text = 'Options Section:';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Visible = 'off';
            app.ParametersLabel.Text = 'Parameters:';
            app.PeakQuantizationLevelMpEditFieldLabel = uilabel(app.UIFigure);
            app.PeakQuantizationLevelMpEditFieldLabel.Visible = 'off';
            app.PeakQuantizationLevelMpEditFieldLabel.Text = 'Peak Quantization Level (Mp)';
            app.PeakQuantizationLevelMpEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakQuantizationLevelMpEditField.Visible = 'off';
            app.NumberofLevelsLEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLevelsLEditFieldLabel.Visible = 'off';
            app.NumberofLevelsLEditFieldLabel.Text = 'Number of Levels (L)';
            app.NumberofLevelsLEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofLevelsLEditField.Visible = 'off';
            app.LawQuantizerEditFieldLabel = uilabel(app.UIFigure);
            app.LawQuantizerEditFieldLabel.Visible = 'off';
            app.LawQuantizerEditFieldLabel.Text = 'Law Quantizer (μ)';
            app.LawQuantizerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LawQuantizerEditField.Visible = 'off';
            app.DisplayFigureButton = uibutton(app.UIFigure, 'push');
            app.DisplayFigureButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFigureButtonPushed, true);
            app.DisplayFigureButton.Visible = 'off';
            app.DisplayFigureButton.Text = 'Display Figure';
            app.MSEButton = uibutton(app.UIFigure, 'push');
            app.MSEButton.ButtonPushedFcn = createCallbackFcn(app, @MSEButtonPushed, true);
            app.MSEButton.Visible = 'off';
            app.MSEButton.Text = 'MSE';
            app.MSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.MSEEditField.Editable = 'off';
            app.MSEEditField.Visible = 'off';
            app.midButtonGroup = uibuttongroup(app.UIFigure);
            app.midButtonGroup.Visible = 'off';
            app.MidRiseButton = uiradiobutton(app.midButtonGroup);
            app.MidRiseButton.Text = 'Mid-Rise';
            app.MidRiseButton.Value = true;
            app.MidTreadButton = uiradiobutton(app.midButtonGroup);
            app.MidTreadButton.Text = 'Mid-Tread';
            app.SamplingFrequencyfsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyfsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyfsEditFieldValueChanged, true);
            app.SamplingFrequencyfsEditField.Visible = 'off';
            app.SamplingFrequencyfsEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyfsEditFieldLabel.Visible = 'off';
            app.SamplingFrequencyfsEditFieldLabel.Text = 'Sampling Frequency (fs)';
            app.ENTERNEWDATAButton = uibutton(app.UIFigure, 'push');
            app.ENTERNEWDATAButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERNEWDATAButtonPushed, true);
            app.ENTERNEWDATAButton.Visible = 'off';
            app.ENTERNEWDATAButton.Text = '----ENTER NEW DATA----';
            app.SpaceLabel = uilabel(app.UIFigure);
            app.SpaceLabel.Visible = 'off';
            app.SpaceLabel.Text = '--------------------------------------------------------------------';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.MSEButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Quantizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end